classdef NetworkGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AdvancedOptionsPanel            matlab.ui.container.Panel
        CutoffMultiplyEditField         matlab.ui.control.NumericEditField
        CutoffMultiplyEditFieldLabel    matlab.ui.control.Label
        MinDegreeKeepEditField          matlab.ui.control.NumericEditField
        MinDegreeKeepEditFieldLabel     matlab.ui.control.Label
        AttemptswoProgressMultiplierEditField  matlab.ui.control.NumericEditField
        AttemptswoProgressMultiplierEditFieldLabel  matlab.ui.control.Label
        GlobalBondLimitMultiplierEditField  matlab.ui.control.NumericEditField
        GlobalBondLimitMultiplierEditFieldLabel  matlab.ui.control.Label
        MaxBondsperAtomEditField        matlab.ui.control.NumericEditField
        MaxBondsperAtomEditFieldLabel   matlab.ui.control.Label
        AtomDensityEditField            matlab.ui.control.NumericEditField
        AtomDensityEditFieldLabel       matlab.ui.control.Label
        LogWindowTextArea               matlab.ui.control.TextArea
        LogWindowTextAreaLabel          matlab.ui.control.Label
        NetworkStatisticsPanel          matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GeneralSettingsPanel            matlab.ui.container.Panel
        TableFileNameEditField          matlab.ui.control.EditField
        TableFileNameEditFieldLabel     matlab.ui.control.Label
        DataFileNameEditField           matlab.ui.control.EditField
        DataFileNameEditFieldLabel      matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        SaveNetworksCheckBox            matlab.ui.control.CheckBox
        SelectFolderButton              matlab.ui.control.Button
        AdvancedOptionsLabel            matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        NetworkSeedLabel                matlab.ui.control.Label
        ShowNetworkStatisticsCheckBox   matlab.ui.control.CheckBox
        PlotOptionsLabel                matlab.ui.control.Label
        SaveOptionsLabel                matlab.ui.control.Label
        NetworkTypeLabel                matlab.ui.control.Label
        NetworkSizeLabel                matlab.ui.control.Label
        SeedEditField                   matlab.ui.control.NumericEditField
        SeedEditFieldLabel              matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        KuhnSegmentLengthEditField      matlab.ui.control.NumericEditField
        KuhnSegmentLengthEditFieldLabel  matlab.ui.control.Label
        NumberofnetworksEditField       matlab.ui.control.NumericEditField
        NumberofnetworksEditFieldLabel  matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        ManualSeedCheckBox              matlab.ui.control.CheckBox
        BimodalOptionsPanel             matlab.ui.container.Panel
        AssignmentModesLabel            matlab.ui.control.Label
        KuhnSegmentsLabel               matlab.ui.control.Label
        DistributionHeightModeDropDown  matlab.ui.control.DropDown
        DistributionHeightModeDropDownLabel  matlab.ui.control.Label
        KuhnSegmentAssignementModeDropDown  matlab.ui.control.DropDown
        KuhnSegmentAssignementModeDropDownLabel  matlab.ui.control.Label
        BinWindowModeDropDown           matlab.ui.control.DropDown
        BinWindowModeDropDownLabel      matlab.ui.control.Label
        Type2FirstCheckBox              matlab.ui.control.CheckBox
        Type2DistributionWidthEditField  matlab.ui.control.NumericEditField
        Type2DistributionWidthEditFieldLabel  matlab.ui.control.Label
        Type1DistributionWidthEditField  matlab.ui.control.NumericEditField
        Type1DistributionWidthEditFieldLabel  matlab.ui.control.Label
        NumberofType2EditField          matlab.ui.control.NumericEditField
        NumberofType2EditFieldLabel     matlab.ui.control.Label
        Type2EditField                  matlab.ui.control.NumericEditField
        Type2EditFieldLabel             matlab.ui.control.Label
        Type1EditField                  matlab.ui.control.NumericEditField
        Type1EditFieldLabel             matlab.ui.control.Label
        PolydisperseOptionsPanel        matlab.ui.container.Panel
        PMFSettingsPanel                matlab.ui.container.Panel
        MaxNuEditField                  matlab.ui.control.NumericEditField
        MaxNuEditFieldLabel             matlab.ui.control.Label
        MeanNuEditField                 matlab.ui.control.NumericEditField
        MeanNuEditFieldLabel            matlab.ui.control.Label
        Nu0EditField                    matlab.ui.control.NumericEditField
        Nu0EditFieldLabel               matlab.ui.control.Label
        RangeSettingsPanel              matlab.ui.container.Panel
        KuhnSegmentsLabel_2             matlab.ui.control.Label
        TargetMaxEditField              matlab.ui.control.NumericEditField
        TargetMaxEditFieldLabel         matlab.ui.control.Label
        TargetMinEditField              matlab.ui.control.NumericEditField
        TargetMinEditFieldLabel         matlab.ui.control.Label
        RangeMethodDropDown             matlab.ui.control.DropDown
        RangeMethodDropDownLabel        matlab.ui.control.Label
        RoundingmodeDropDown            matlab.ui.control.DropDown
        RoundingmodeDropDownLabel       matlab.ui.control.Label
        LengthAlignmentModeDropDown     matlab.ui.control.DropDown
        LengthAlignmentModeDropDownLabel  matlab.ui.control.Label
        MinimumKuhnSegmentEditField     matlab.ui.control.NumericEditField
        MinimumKuhnSegmentEditFieldLabel  matlab.ui.control.Label
        AssignmentModesLabel_2          matlab.ui.control.Label
        KuhnSegmentAssignmentModeDropDown  matlab.ui.control.DropDown
        KuhnSegmentAssignmentModeDropDownLabel  matlab.ui.control.Label
        GenerateNetworkButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NetworkObject
        logListener
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            %% A. Assign UI Values to the network properties object
            % General Settings
            app.NetworkObject.dist_type = app.DistributionTypeDropDown.Value;
            app.NetworkObject.Nreplicates = app.NumberofnetworksEditField.Value;
            app.NetworkObject.b = app.KuhnSegmentLengthEditField.Value;
            app.NetworkObject.Lx = app.LengthEditField.Value;
            app.NetworkObject.Ly = app.HeightEditField.Value;
            app.NetworkObject.imanualseed = app.ManualSeedCheckBox.Value;
            app.NetworkObject.seed = app.SeedEditField.Value;
            
            app.NetworkObject.iplot = app.ShowNetworkStatisticsCheckBox.Value;
            app.NetworkObject.isave = app.SaveNetworksCheckBox.Value;
            app.NetworkObject.lammps_data_file = app.DataFileNameEditField.Value;
            app.NetworkObject.bond_table_file = app.TableFileNameEditField.Value;
            
            % Polydisperse Settings
            app.NetworkObject.distribution_assignment_mode_poly = app.KuhnSegmentAssignmentModeDropDown.Value;
            app.NetworkObject.min_N_poly = app.MinimumKuhnSegmentEditField.Value;
            app.NetworkObject.align_to_length_poly = app.LengthAlignmentModeDropDown.Value;
            app.NetworkObject.kuhn_rounding_poly = app.RoundingmodeDropDown.Value;
            app.NetworkObject.N_range_method_poly = app.RangeMethodDropDown.Value;
            app.NetworkObject.N_target_min_poly = app.TargetMinEditField.Value;
            app.NetworkObject.N_target_max_poly = app.TargetMaxEditField.Value;
            app.NetworkObject.pmf_nu0_poly = app.Nu0EditField.Value;
            app.NetworkObject.pmf_meanN_poly = app.MeanNuEditField.Value;
            app.NetworkObject.pmf_nu_max_poly = app.MaxNuEditField.Value;

            % Bimodal Settings
            app.NetworkObject.N1 = app.Type1EditField.Value;
            app.NetworkObject.N2 = app.Type2EditField.Value;
            app.NetworkObject.bin_window_method = app.BinWindowModeDropDown.Value;
            app.NetworkObject.distribution_assignment_mode = app.KuhnSegmentAssignementModeDropDown.Value;
            app.NetworkObject.distribution_height_mode = app.DistributionHeightModeDropDown.Value;
            app.NetworkObject.long_first = app.Type2FirstCheckBox.Value;
            app.NetworkObject.P = app.NumberofType2EditField.Value; 
            app.NetworkObject.N2_bonds = app.NumberofType2EditField.Value;
            app.NetworkObject.dr1 = app.Type1DistributionWidthEditField.Value;
            app.NetworkObject.dr2 = app.Type2DistributionWidthEditField.Value;

            % Advanced Settings
            app.NetworkObject.iadvancedoptions = app.CheckBox.Value;
            app.NetworkObject.rho_atom = app.AtomDensityEditField.Value;
            app.NetworkObject.max_peratom_bond = app.MaxBondsperAtomEditField.Value;
            app.NetworkObject.bond_global_try_limit_multiplier = app.GlobalBondLimitMultiplierEditField.Value;
            app.NetworkObject.max_attempts_without_progress_multiplier = app.AttemptswoProgressMultiplierEditField.Value;
            app.NetworkObject.min_degree_keep = app.MinDegreeKeepEditField.Value;
            app.NetworkObject.cutoff_multiply = app.CutoffMultiplyEditField.Value;
            
            %% B. Send the network object to the generator
            [~,Atoms,Bonds,Nvec] = generateNetwork(app.NetworkObject);
            
            %% C. Unpack information and visualize results in the UI Windows
            % extract data from atoms
            x = Atoms(:,2);
            y = Atoms(:,3);
            Total_bond = length(Bonds);
            
            b = app.NetworkObject.b;
            xlo = min(x); xhi = max(x);
            ylo = min(y); yhi = max(y);

            axis(app.UIAxes,[xlo - 5*b, xhi + 5*b, ylo - 5*b, yhi + 5*b]);

            scatter(app.UIAxes,x,y, 6, 'k', 'filled')
            hold(app.UIAxes,'on')
            for ii = 1:Total_bond
                i1 = Bonds(ii,2);
                i2 = Bonds(ii,3);
                if strcmpi(app.NetworkObject.dist_type,'bimodal')
                    if Bonds(ii,5) == 1
                        plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'k-');
                    else
                        plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'r-');
                    end
                else
                    plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'k-')
                end
            end
            hold(app.UIAxes,'off')

            if app.NetworkObject.iplot
                % plot kuhn segment distribution
                %nbins = max(10, min(80, ceil(sqrt(numel(Nvec)))));
                histogram(app.UIAxes2,Nvec, 30)
                xlabel(app.UIAxes2,'Assigned N per bond'); ylabel(app.UIAxes2,'Count');
                title(app.UIAxes2,'N distribution');
                
                % plot bond length distribution
                if strcmpi(app.NetworkObject.dist_type,'bimodal')
                        type1 = Bonds(:,5) == 1;
                        hold(app.UIAxes3,'on')
                        histogram(app.UIAxes3,Bonds(type1,4),30,'FaceColor',[0.2 0.2 0.2],'FaceAlpha',1,'LineWidth',0.0005);
                        histogram(app.UIAxes3,Bonds(~type1,4),30,'FaceColor',[1 0 0],'FaceAlpha',1,'LineWidth',0.0005);
                        xlabel(app.UIAxes3,'Bond length L'); ylabel(app.UIAxes3,'Count'); title(app.UIAxes3,'Length distribution (final)')
                        %set(gca,'FontSize',16,'LineWidth',2)
                else
                        histogram(app.UIAxes3,Bonds(:,4),30,'FaceColor',[0.2 0.2 0.2],'FaceAlpha',1,'LineWidth',0.0005);
                end
                hold(app.UIAxes3,'off')

            end
    
        end
        
        %% D. Log updates
        function onLogEvent(app, src, ~)
            % If the log is updated, write to the log window.
            newLog = src.log;
            app.LogWindowTextArea.Value = newLog;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %create network object
            app.NetworkObject = network;
            
            % Attach a PostSet listener to the observable `log` property so the callback
            % receives event data containing AffectedObject (ev.AffectedObject.log)
            app.logListener = addlistener(app.NetworkObject, 'logUpdated', @(src,ev) app.onLogEvent(src,ev));
        end

        % Button pushed function: GenerateNetworkButton
        function GenerateNetworkButtonPushed(app, event)
            
            % Need to do some checks if save option enabled
            if app.SaveNetworksCheckBox.Value
                
                sprintf('%s',app.EditField.Value);
                %if no locations and names are given, auto populate
                if isempty(app.EditField.Value)
                    app.EditField.Value = pwd;
                end
                if isempty(app.DataFileNameEditField.Value)
                    app.DataFileNameEditField.Value = 'Network_nano.dat';
                end
                if isempty(app.TableFileNameEditField.Value)
                    app.TableFileNameEditField.Value = 'bond.table';
                end

            end
            
            % Looks like someone pushed the button... - Make a network!
            updatePlot(app)
        end

        % Value changed function: DistributionTypeDropDown
        function DistributionTypeDropDownValueChanged(app, event)
            value = app.DistributionTypeDropDown.Value;
            
            % Toggle the visibility of option menus
            if strcmpi(value,'bimodal')
                app.BimodalOptionsPanel.Visible = "on";
                app.PolydisperseOptionsPanel.Visible = 'off';
            elseif strcmpi(value,'polydisperse')
                app.PolydisperseOptionsPanel.Visible = "on";
                app.BimodalOptionsPanel.Visible = 'off';
            else
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.NetworkObject.write_location = uigetdir;
            app.EditField.Value = app.NetworkObject.write_location;
        end

        % Value changed function: DistributionHeightModeDropDown
        function DistributionHeightModeDropDownValueChanged(app, event)
            value = app.DistributionHeightModeDropDown.Value;
            
            % Toggle the visibility of the fields
            if strcmpi(value,"fixed")
                app.NumberofType2EditFieldLabel.Text = "Number of Type 2";
            elseif strcmpi(value,'prob')
                app.NumberofType2EditFieldLabel.Text = 'Fraction of Type 2';
            else
            end
        end

        % Value changed function: BinWindowModeDropDown
        function BinWindowModeDropDownValueChanged(app, event)
            value = app.BinWindowModeDropDown.Value;
            
            if strcmpi(value,'manual')
                app.Type1DistributionWidthEditField.Editable = 'on';
                app.Type2DistributionWidthEditField.Editable = 'on';
            else
                app.Type1DistributionWidthEditField.Editable = 'off';
                app.Type2DistributionWidthEditField.Editable = 'off';
            end
        end

        % Value changed function: KuhnSegmentAssignmentModeDropDown
        function KuhnSegmentAssignmentModeDropDownValueChanged(app, event)
            value = app.KuhnSegmentAssignmentModeDropDown.Value;
            
            if strcmpi(value,'pmf')
                app.PMFSettingsPanel.Visible = 'on';
                app.RangeSettingsPanel.Visible = 'off';
            elseif strcmpi(value,'range')
                app.PMFSettingsPanel.Visible = 'off';
                app.RangeSettingsPanel.Visible = 'on';
            else
                app.PMFSettingsPanel.Visible = 'off';
                app.RangeSettingsPanel.Visible = 'off';
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                app.AdvancedOptionsPanel.Enable = "on";
            else
                 app.AdvancedOptionsPanel.Enable = "off";
            end
        end

        % Value changed function: ShowNetworkStatisticsCheckBox
        function ShowNetworkStatisticsCheckBoxValueChanged(app, event)
            value = app.ShowNetworkStatisticsCheckBox.Value;
            if value
                app.NetworkStatisticsPanel.Enable = "on";
            else
                 app.NetworkStatisticsPanel.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.ZTick = [];
            app.GenerateNetworkButton = uibutton(app.UIFigure, 'push');
            app.GenerateNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNetworkButtonPushed, true);
            app.GenerateNetworkButton.Text = 'Generate Network';
            app.PolydisperseOptionsPanel = uipanel(app.UIFigure);
            app.PolydisperseOptionsPanel.Title = 'Polydisperse Options';
            app.KuhnSegmentAssignmentModeDropDownLabel = uilabel(app.PolydisperseOptionsPanel);
            app.KuhnSegmentAssignmentModeDropDownLabel.Text = 'Kuhn Segment Assignment Mode';
            app.KuhnSegmentAssignmentModeDropDown = uidropdown(app.PolydisperseOptionsPanel);
            app.KuhnSegmentAssignmentModeDropDown.Items = {'geom', 'range', 'pmf'};
            app.KuhnSegmentAssignmentModeDropDown.ValueChangedFcn = createCallbackFcn(app, @KuhnSegmentAssignmentModeDropDownValueChanged, true);
            app.KuhnSegmentAssignmentModeDropDown.Value = 'pmf';
            app.AssignmentModesLabel_2 = uilabel(app.PolydisperseOptionsPanel);
            app.AssignmentModesLabel_2.Text = 'Assignment Modes';
            app.MinimumKuhnSegmentEditFieldLabel = uilabel(app.PolydisperseOptionsPanel);
            app.MinimumKuhnSegmentEditFieldLabel.Text = 'Minimum Kuhn Segment';
            app.MinimumKuhnSegmentEditField = uieditfield(app.PolydisperseOptionsPanel, 'numeric');
            app.MinimumKuhnSegmentEditField.Value = 1;
            app.LengthAlignmentModeDropDownLabel = uilabel(app.PolydisperseOptionsPanel);
            app.LengthAlignmentModeDropDownLabel.Text = 'Length Alignment Mode';
            app.LengthAlignmentModeDropDown = uidropdown(app.PolydisperseOptionsPanel);
            app.LengthAlignmentModeDropDown.Items = {'ascend', 'none'};
            app.LengthAlignmentModeDropDown.Value = 'ascend';
            app.RoundingmodeDropDownLabel = uilabel(app.PolydisperseOptionsPanel);
            app.RoundingmodeDropDownLabel.Text = 'Rounding mode';
            app.RoundingmodeDropDown = uidropdown(app.PolydisperseOptionsPanel);
            app.RoundingmodeDropDown.Items = {'round', 'ceil', 'floor'};
            app.RoundingmodeDropDown.Value = 'round';
            app.RangeSettingsPanel = uipanel(app.PolydisperseOptionsPanel);
            app.RangeSettingsPanel.Title = 'Range Settings';
            app.RangeMethodDropDownLabel = uilabel(app.RangeSettingsPanel);
            app.RangeMethodDropDownLabel.Text = 'Range Method';
            app.RangeMethodDropDown = uidropdown(app.RangeSettingsPanel);
            app.RangeMethodDropDown.Items = {'Rank', 'Linear'};
            app.RangeMethodDropDown.Value = 'Rank';
            app.TargetMinEditFieldLabel = uilabel(app.RangeSettingsPanel);
            app.TargetMinEditFieldLabel.Text = 'Target Min';
            app.TargetMinEditField = uieditfield(app.RangeSettingsPanel, 'numeric');
            app.TargetMinEditField.Value = 20;
            app.TargetMaxEditFieldLabel = uilabel(app.RangeSettingsPanel);
            app.TargetMaxEditFieldLabel.Text = 'Target Max';
            app.TargetMaxEditField = uieditfield(app.RangeSettingsPanel, 'numeric');
            app.TargetMaxEditField.Value = 120;
            app.KuhnSegmentsLabel_2 = uilabel(app.RangeSettingsPanel);
            app.KuhnSegmentsLabel_2.Text = 'Kuhn Segments';
            app.PMFSettingsPanel = uipanel(app.PolydisperseOptionsPanel);
            app.PMFSettingsPanel.Title = 'PMF Settings';
            app.Nu0EditFieldLabel = uilabel(app.PMFSettingsPanel);
            app.Nu0EditFieldLabel.Text = 'Nu0';
            app.Nu0EditField = uieditfield(app.PMFSettingsPanel, 'numeric');
            app.Nu0EditField.Value = 20;
            app.MeanNuEditFieldLabel = uilabel(app.PMFSettingsPanel);
            app.MeanNuEditFieldLabel.Text = 'Mean Nu';
            app.MeanNuEditField = uieditfield(app.PMFSettingsPanel, 'numeric');
            app.MeanNuEditField.Value = 30;
            app.MaxNuEditFieldLabel = uilabel(app.PMFSettingsPanel);
            app.MaxNuEditFieldLabel.Text = 'Max Nu';
            app.MaxNuEditField = uieditfield(app.PMFSettingsPanel, 'numeric');
            app.MaxNuEditField.Value = 60;
            app.BimodalOptionsPanel = uipanel(app.UIFigure);
            app.BimodalOptionsPanel.Title = 'Bimodal Options';
            app.Type1EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type1EditFieldLabel.Text = 'Type 1';
            app.Type1EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type1EditField.Value = 20;
            app.Type2EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type2EditFieldLabel.Text = 'Type 2';
            app.Type2EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type2EditField.Value = 50;
            app.NumberofType2EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.NumberofType2EditFieldLabel.Text = 'Number of Type 2';
            app.NumberofType2EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type1DistributionWidthEditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type1DistributionWidthEditFieldLabel.Text = 'Type 1 Distribution Width';
            app.Type1DistributionWidthEditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type1DistributionWidthEditField.Value = 10;
            app.Type2DistributionWidthEditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.Type2DistributionWidthEditFieldLabel.Text = 'Type 2 Distribution Width';
            app.Type2DistributionWidthEditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.Type2DistributionWidthEditField.Value = 10;
            app.Type2FirstCheckBox = uicheckbox(app.BimodalOptionsPanel);
            app.Type2FirstCheckBox.Text = 'Type 2 First';
            app.BinWindowModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.BinWindowModeDropDownLabel.Text = 'Bin Window Mode';
            app.BinWindowModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.BinWindowModeDropDown.Items = {'manual', 'adaptive'};
            app.BinWindowModeDropDown.ValueChangedFcn = createCallbackFcn(app, @BinWindowModeDropDownValueChanged, true);
            app.BinWindowModeDropDown.Value = 'manual';
            app.KuhnSegmentAssignementModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.KuhnSegmentAssignementModeDropDownLabel.Text = 'Kuhn Segment Assignement Mode';
            app.KuhnSegmentAssignementModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.KuhnSegmentAssignementModeDropDown.Items = {'single', 'geom', 'gaussian'};
            app.KuhnSegmentAssignementModeDropDown.Value = 'gaussian';
            app.DistributionHeightModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.DistributionHeightModeDropDownLabel.Text = 'Distribution Height Mode';
            app.DistributionHeightModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.DistributionHeightModeDropDown.Items = {'prob', 'fixed'};
            app.DistributionHeightModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionHeightModeDropDownValueChanged, true);
            app.DistributionHeightModeDropDown.Value = 'fixed';
            app.KuhnSegmentsLabel = uilabel(app.BimodalOptionsPanel);
            app.KuhnSegmentsLabel.Text = 'Kuhn Segments';
            app.AssignmentModesLabel = uilabel(app.BimodalOptionsPanel);
            app.AssignmentModesLabel.Text = 'Assignment Modes';
            app.GeneralSettingsPanel = uipanel(app.UIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.ManualSeedCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.ManualSeedCheckBox.Text = 'Manual Seed';
            app.DistributionTypeDropDown = uidropdown(app.GeneralSettingsPanel);
            app.DistributionTypeDropDown.Items = {'polydisperse', 'bimodal'};
            app.DistributionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionTypeDropDownValueChanged, true);
            app.DistributionTypeDropDown.Value = 'bimodal';
            app.NumberofnetworksEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.NumberofnetworksEditFieldLabel.Text = 'Number of networks';
            app.NumberofnetworksEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.NumberofnetworksEditField.Limits = [1 Inf];
            app.NumberofnetworksEditField.Value = 1;
            app.KuhnSegmentLengthEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.KuhnSegmentLengthEditFieldLabel.Text = 'Kuhn Segment Length';
            app.KuhnSegmentLengthEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.KuhnSegmentLengthEditField.Limits = [0 Inf];
            app.KuhnSegmentLengthEditField.Value = 1.6;
            app.LengthEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.LengthEditField.Value = 100;
            app.HeightEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.HeightEditField.Value = 100;
            app.SeedEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.NetworkSizeLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkSizeLabel.Text = 'Network Size';
            app.NetworkTypeLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkTypeLabel.Text = 'Network Type';
            app.SaveOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.PlotOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.ShowNetworkStatisticsCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.ShowNetworkStatisticsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNetworkStatisticsCheckBoxValueChanged, true);
            app.ShowNetworkStatisticsCheckBox.Text = 'Show Network Statistics';
            app.ShowNetworkStatisticsCheckBox.Value = true;
            app.NetworkSeedLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkSeedLabel.Text = 'Network Seed';
            app.CheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.AdvancedOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.AdvancedOptionsLabel.Text = 'Advanced Options';
            app.SelectFolderButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SaveNetworksCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.SaveNetworksCheckBox.Text = 'Save Networks';
            app.EditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.EditField.Editable = 'off';
            app.DataFileNameEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.DataFileNameEditFieldLabel.Text = 'Data File Name';
            app.DataFileNameEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.TableFileNameEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.TableFileNameEditFieldLabel.Text = 'Table File Name';
            app.TableFileNameEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.NetworkStatisticsPanel = uipanel(app.UIFigure);
            app.NetworkStatisticsPanel.Title = 'Network Statistics';
            app.UIAxes2 = uiaxes(app.NetworkStatisticsPanel);
            title(app.UIAxes2, 'N distribution')
            xlabel(app.UIAxes2, 'Assigned N per bond')
            ylabel(app.UIAxes2, 'Count')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.NetworkStatisticsPanel);
            title(app.UIAxes3, 'Length distribution (final)')
            xlabel(app.UIAxes3, 'Bond Length L')
            ylabel(app.UIAxes3, 'Count')
            zlabel(app.UIAxes3, 'Z')
            app.LogWindowTextAreaLabel = uilabel(app.UIFigure);
            app.LogWindowTextAreaLabel.Text = 'Log Window';
            app.LogWindowTextArea = uitextarea(app.UIFigure);
            app.LogWindowTextArea.Editable = 'off';
            app.AdvancedOptionsPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsPanel.Enable = 'off';
            app.AdvancedOptionsPanel.Title = 'Advanced Options';
            app.AtomDensityEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.AtomDensityEditFieldLabel.Text = 'Atom Density';
            app.AtomDensityEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.AtomDensityEditField.Value = 0.0078;
            app.MaxBondsperAtomEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.MaxBondsperAtomEditFieldLabel.Text = 'Max Bonds per Atom';
            app.MaxBondsperAtomEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.MaxBondsperAtomEditField.Value = 5;
            app.GlobalBondLimitMultiplierEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.GlobalBondLimitMultiplierEditFieldLabel.Text = 'Global Bond Limit Multiplier';
            app.GlobalBondLimitMultiplierEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.GlobalBondLimitMultiplierEditField.Value = 200;
            app.AttemptswoProgressMultiplierEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.AttemptswoProgressMultiplierEditFieldLabel.Text = 'Attempts w/o Progress Multiplier';
            app.AttemptswoProgressMultiplierEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.AttemptswoProgressMultiplierEditField.Value = 10;
            app.MinDegreeKeepEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.MinDegreeKeepEditFieldLabel.Text = 'Min Degree Keep';
            app.MinDegreeKeepEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.MinDegreeKeepEditField.Value = 1;
            app.CutoffMultiplyEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.CutoffMultiplyEditFieldLabel.Text = 'Cutoff Multiply';
            app.CutoffMultiplyEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.CutoffMultiplyEditField.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end